classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BrowseButton                  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        DirectoryEditFieldLabel       matlab.ui.control.Label
        DirectoryEditField            matlab.ui.control.EditField
        CompressionRationSliderLabel  matlab.ui.control.Label
        CompressionRationSlider       matlab.ui.control.Slider
        StartCamButton                matlab.ui.control.Button
        EndButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        cam=0;
        capture=false
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           %get pass of selected folder
           inputDir=uigetdir;
            %take all names and data from dicrectory , .png to esclude only png files
            pngFilesY = dir(strcat(inputDir,'\*Y*.bin'));
            pngFilescb = dir(strcat(inputDir,'\*cb*.bin'));
            pngFilescr = dir(strcat(inputDir,'\*cr*.bin'));
            pngFilesY.folder
           
            count = length(pngFilesY);
            prog = waitbar(0,"wait");
             for i = 1:count  
                framey = readbin((strcat( pngFilesY(i).folder,'\', pngFilesY(i).name)),90,160);
                 framecb = readbin((strcat( pngFilescb(i).folder,'\', pngFilescb(i).name)),45,80);
                 framecr = readbin((strcat( pngFilescr(i).folder,'\', pngFilescr(i).name)),45,80);
                 frame = decoding(framey,framey,framecb,framecr);
                 waitbar(i/count,prog,"Processing");
               pause(0.10)
     
           
                 app.Image.ImageSource = frame;
              
%              
             end
            waitbar(1,prog,"done");
            close(prog); 
        end

        % Value changed function: CompressionRationSlider
        function CompressionRationSliderValueChanged(app, event)
            value = app.CompressionRationSlider.Value;
            app.DirectoryEditField.Value=int2str(value);
        end

        % Button pushed function: StartCamButton
        function StartCamButtonPushed(app, event)
            app.cam=webcam(1);
            app.capture=true;
            count=0;
            while app.capture==true
                frame= snapshot(app.cam);
                 %resize frame
               frame= imresize(frame,0.25);
                frame=flip(frame,2);
                imwrite(frame,strcat("C:\\Users\\fadys\\Desktop\\Digital audio\\week9\\raw\\saved",int2str(count),".png"));
                
                [cframe,yframe,cbframe,crframe]=encoding(frame);
                % imwrite(yframe,strcat("C:\\Users\\fadys\\Desktop\\Digital audio\\week9\\compressed\\savedY",int2str(count),".bin"));
                 %imwrite(cbframe,strcat("C:\\Users\\fadys\\Desktop\\Digital audio\\week9\\compressed\\savedcb",int2str(count),".bin"));
                 %imwrite(crframe,strcat("C:\\Users\\fadys\\Desktop\\Digital audio\\week9\\compressed\\savedcr",int2str(count),".bin"));
                 count=count+1;
                 savebin(yframe,cbframe,crframe,count);
                 
                
                 %to fix flikering
                pause(0.10)
                app.Image.ImageSource=frame;
                 if count == 10
                    app.capture = false;
                    clear app.cam
                    
                 end
            end
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.capture=false
            clear app.cam
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = '4x4.png';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.CompressionRationSliderLabel = uilabel(app.UIFigure);
            app.CompressionRationSliderLabel.Text = {'Compression Ration'; ''};
            app.CompressionRationSlider = uislider(app.UIFigure);
            app.CompressionRationSlider.Limits = [4 16];
            app.CompressionRationSlider.MajorTicks = [4 8 16];
            app.CompressionRationSlider.ValueChangedFcn = createCallbackFcn(app, @CompressionRationSliderValueChanged, true);
            app.CompressionRationSlider.MinorTicks = [];
            app.CompressionRationSlider.Value = 4;
            app.StartCamButton = uibutton(app.UIFigure, 'push');
            app.StartCamButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCamButton.Text = {'Start Cam'; ''; ''};
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end